classdef lsEqualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        laSalleEqualizerUIFigure     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        OpenfileMenu                 matlab.ui.container.Menu
        SavefileMenu                 matlab.ui.container.Menu
        Image                        matlab.ui.control.Image
        CenterFreqHzKnobLabel        matlab.ui.control.Label
        CenterFreqHzKnob             matlab.ui.control.Knob
        GaindBKnobLabel              matlab.ui.control.Label
        GaindBKnob                   matlab.ui.control.Knob
        BandwithHzKnob_2Label        matlab.ui.control.Label
        BandwithHzKnob_2             matlab.ui.control.Knob
        Lamp                         matlab.ui.control.Lamp
        Lamp_2                       matlab.ui.control.Lamp
        BandwithHzKnob_3Label        matlab.ui.control.Label
        BandwithHzKnob_3             matlab.ui.control.Knob
        CenterFreqHzKnob_2Label      matlab.ui.control.Label
        CenterFreqHzKnob_2           matlab.ui.control.Knob
        GaindBKnob_2Label            matlab.ui.control.Label
        GaindBKnob_2                 matlab.ui.control.Knob
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        FilterFrequencyReponseLabel  matlab.ui.control.Label
        ZeroPolePlotLabel            matlab.ui.control.Label
        Switch                       matlab.ui.control.Switch
        Switch_2                     matlab.ui.control.Switch
        PlayButton                   matlab.ui.control.Button
        StopButton                   matlab.ui.control.Button
        UIAxes3                      matlab.ui.control.UIAxes
        EditField                    matlab.ui.control.NumericEditField
        EditField_2                  matlab.ui.control.NumericEditField
    end

    %VARIABLES DEL SISTEMA
    properties (Access = private)
        fs = 16000; % Frecuencia de muestreo
        input = 'music.wav'; % Audio leido
        output = 'archivoFiltrado.wav';
        player;
        f0;
        bw;
        GB;
        xInput;
        FsInput;
        yParameq;
    end
    
    methods (Access = private)
        
        function plotsignals(app, B, A)
            %Respuesta frecuencial
            [h,w] = freqz(B,A, app.fs);
            H = 20*log10(abs(h));
            plot(app.UIAxes, w*(800*pi), H)
            xlabel(app.UIAxes, 'Frequency [Hz]') 
            ylabel(app.UIAxes, 'Gain [dB]')
            ylim(app.UIAxes, [-40 40]);
            
            %Señal (original y filtrada)
            if(app.Switch.Value == "Time")
                %Original
                plot(app.UIAxes3, app.xInput);
                hold(app.UIAxes3, 'on')
                
                %Filtrada
                plot(app.UIAxes3, app.yParameq, 'color', [0.8, 0.3, 0.1]);
                hold(app.UIAxes3, 'off');
                xlabel(app.UIAxes3, 'Seconds') 
                ylabel(app.UIAxes3, 'Amplitude')
                legend(app.UIAxes3,'Original','Filtered');
            end
            if (app.Switch.Value == "Frequency")
                %Original
                x = app.xInput;
                x_freq = fft(x);
                lengthX = length(x_freq);
                x_freq = x_freq(1:lengthX/2+1);
                x_freq = 20*log10(abs(x_freq));
                fx = 0:app.fs/lengthX:app.fs/2;
                
                plot(app.UIAxes3, fx, x_freq);
                hold(app.UIAxes3, 'on')
                
                %Filtrada
                y = app.yParameq;
                y_freq = fft(y);
                lengthY = length(y);
                y_freq = y_freq(1:lengthY/2+1);
                y_freq = 20*log10(abs(y_freq));
                fy = 0:app.fs/lengthY:app.fs/2;

                plot(app.UIAxes3, fy, y_freq, 'r');
                hold(app.UIAxes3, 'off');
                xlabel(app.UIAxes3, 'Frequency [Hz]') 
                ylabel(app.UIAxes3, 'Magnitude (lineal)')
                legend(app.UIAxes3,'Original','Filtered');
            end
            cla(app.UIAxes2);
            %Creamos el círculo unidad
            radio = 1;
            circulo_unidad = 0:pi/30:2*pi;
            x = radio*cos(circulo_unidad);
            y = radio*sin(circulo_unidad);
            plot(app.UIAxes2, x,y,'--g');
            %Con roots obtenemos las raíces de los polinomios.
            ceros=roots(A);
            polos = roots(B);
            plot(app.UIAxes2,ceros,'ob');
            hold(app.UIAxes2, 'on');
            plot(app.UIAxes2,polos,'xr');
            xlim(app.UIAxes2, [-1 1]);
            ylim(app.UIAxes2, [-1 1]);
            close all
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CenterFreqHzKnob.Value = 300;
            app.BandwithHzKnob_2.Value = 200;
            app.GaindBKnob.Value = 3;
            app.CenterFreqHzKnob_2.Value = 4000;
            app.BandwithHzKnob_3.Value = 200;
            app.GaindBKnob_2.Value = 3;
            app.EditField.Value = app.CenterFreqHzKnob.Value;
            app.EditField_2.Value = app.CenterFreqHzKnob_2.Value;
            [app.xInput, app.FsInput] = audioread(app.input);
            app.player = audioplayer(app.xInput, app.FsInput);
            app.f0 = [app.CenterFreqHzKnob.Value app.CenterFreqHzKnob_2.Value];
            app.bw = [app.BandwithHzKnob_2.Value app.BandwithHzKnob_3.Value];
            app.GB = [app.GaindBKnob.Value app.GaindBKnob_2.Value];
            [B, A, app.yParameq] = parameq_fullband(app.f0, app.fs, app.bw, app.GB, app.xInput);
            plotsignals(app, B, A);
        end

        % Menu selected function: SavefileMenu
        function SavefileMenuSelected(app, event)
            [file,path] = uiputfile('*.wav');
            if isnumeric(file)
               return;
            end
            filename = fullfile(path, file);
            audiowrite(filename, app.yParameq, app.FsInput);
        end

        % Menu selected function: OpenfileMenu
        function OpenfileMenuSelected(app, event)
           try
                app.input= uigetfile('.wav');
                [app.xInput, app.FsInput] = audioread(app.input);
                app.player = audioplayer(app.xInput, app.FsInput);
                [B, A, app.yParameq] = parameq_fullband(app.f0, app.fs, app.bw, app.GB, app.xInput);
                plotsignals(app, B, A);
           catch
                disp("Ha ocurrido un error al abrir el fichero");
                return;
           end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if(app.Switch_2.Value == "Original")
                [app.xInput,app.FsInput] = audioread(app.input);
                app.player = audioplayer(app.xInput,app.FsInput);
                play(app.player);
            else
                app.player = audioplayer(app.yParameq,app.FsInput);
                play(app.player);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.player);
        end

        % Value changed function: CenterFreqHzKnob
        function CenterFreqHzKnobValueChanged(app, event)
            value = app.CenterFreqHzKnob.Value;
            app.EditField.Value = value;
            app.f0(1) = value;
            [B, A, app.yParameq] = parameq_fullband(app.f0, app.fs, app.bw, app.GB, app.xInput);
            plotsignals(app, B, A);
            close all
        end

        % Value changed function: BandwithHzKnob_2
        function BandwithHzKnob_2ValueChanged(app, event)
            value = app.BandwithHzKnob_2.Value;
            app.bw(1) = value;
            [B, A, app.yParameq] = parameq_fullband(app.f0, app.fs, app.bw, app.GB, app.xInput);
            plotsignals(app, B, A);
            close all
        end

        % Value changed function: GaindBKnob
        function GaindBKnobValueChanged(app, event)
            value = app.GaindBKnob.Value;
            app.GB(1) = value;
            [B, A, app.yParameq] = parameq_fullband(app.f0, app.fs, app.bw, app.GB, app.xInput);
            plotsignals(app, B, A);
            close all
        end

        % Value changed function: CenterFreqHzKnob_2
        function CenterFreqHzKnob_2ValueChanged(app, event)
            value = app.CenterFreqHzKnob_2.Value;
            app.EditField_2.Value = value;
            app.f0(2) = value;
            [B, A, app.yParameq] = parameq_fullband(app.f0, app.fs, app.bw, app.GB, app.xInput);
            plotsignals(app, B, A);
            close all
        end

        % Value changed function: BandwithHzKnob_3
        function BandwithHzKnob_3ValueChanged(app, event)
            value = app.BandwithHzKnob_3.Value;
            app.bw(2) = value;
            [B, A, app.yParameq] = parameq_fullband(app.f0, app.fs, app.bw, app.GB, app.xInput);
            plotsignals(app, B, A);
            close all
        end

        % Value changed function: GaindBKnob_2
        function GaindBKnob_2ValueChanged(app, event)
            value = app.GaindBKnob_2.Value;
            app.GB(2) = value;
            [B, A, app.yParameq] = parameq_fullband(app.f0, app.fs, app.bw, app.GB, app.xInput);
            plotsignals(app, B, A);
            close all
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            [B, A, app.yParameq] = parameq_fullband(app.f0, app.fs, app.bw, app.GB, app.xInput);
            plotsignals(app, B, A);
            close all
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            if(value > 8000)
                app.CenterFreqHzKnob.Value = 8000;
            elseif (value < 10)
                 app.CenterFreqHzKnob.Value = 10;
            else
                app.CenterFreqHzKnob.Value = value;
            end
            app.f0(1) = value;
            [B, A, app.yParameq] = parameq_fullband(app.f0, app.fs, app.bw, app.GB, app.xInput);
            plotsignals(app, B, A);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            if(value > 8000)
                app.CenterFreqHzKnob_2.Value = 8000;
            elseif (value < 10)
                 app.CenterFreqHzKnob_2.Value = 10;
            else
                app.CenterFreqHzKnob_2.Value = value;
            end
            app.f0(2) = value;
            [B, A, app.yParameq] = parameq_fullband(app.f0, app.fs, app.bw, app.GB, app.xInput);
            plotsignals(app, B, A);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.laSalleEqualizerUIFigure = uifigure('Visible', 'off');
            app.laSalleEqualizerUIFigure.Name = 'laSalle Equalizer';
            app.FileMenu = uimenu(app.laSalleEqualizerUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenfileMenu = uimenu(app.FileMenu);
            app.OpenfileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenfileMenuSelected, true);
            app.OpenfileMenu.Text = 'Open file';
            app.SavefileMenu = uimenu(app.FileMenu);
            app.SavefileMenu.MenuSelectedFcn = createCallbackFcn(app, @SavefileMenuSelected, true);
            app.SavefileMenu.Text = 'Save file';
            app.Image = uiimage(app.laSalleEqualizerUIFigure);
            app.Image.ImageSource = 'FondoGUI_Mesa de trabajo 1.jpg';
            app.CenterFreqHzKnobLabel = uilabel(app.laSalleEqualizerUIFigure);
            app.CenterFreqHzKnobLabel.Text = 'Center Freq [Hz]';
            app.CenterFreqHzKnob = uiknob(app.laSalleEqualizerUIFigure, 'continuous');
            app.CenterFreqHzKnob.Limits = [10 8000];
            app.CenterFreqHzKnob.ValueChangedFcn = createCallbackFcn(app, @CenterFreqHzKnobValueChanged, true);
            app.CenterFreqHzKnob.Value = 10;
            app.GaindBKnobLabel = uilabel(app.laSalleEqualizerUIFigure);
            app.GaindBKnobLabel.Text = 'Gain [dB]';
            app.GaindBKnob = uiknob(app.laSalleEqualizerUIFigure, 'continuous');
            app.GaindBKnob.Limits = [-40 40];
            app.GaindBKnob.ValueChangedFcn = createCallbackFcn(app, @GaindBKnobValueChanged, true);
            app.GaindBKnob.Value = 10;
            app.BandwithHzKnob_2Label = uilabel(app.laSalleEqualizerUIFigure);
            app.BandwithHzKnob_2Label.Text = 'Bandwith [Hz]';
            app.BandwithHzKnob_2 = uiknob(app.laSalleEqualizerUIFigure, 'continuous');
            app.BandwithHzKnob_2.Limits = [0 1000];
            app.BandwithHzKnob_2.ValueChangedFcn = createCallbackFcn(app, @BandwithHzKnob_2ValueChanged, true);
            app.BandwithHzKnob_2.Value = 10;
            app.Lamp = uilamp(app.laSalleEqualizerUIFigure);
            app.Lamp_2 = uilamp(app.laSalleEqualizerUIFigure);
            app.BandwithHzKnob_3Label = uilabel(app.laSalleEqualizerUIFigure);
            app.BandwithHzKnob_3Label.Text = 'Bandwith [Hz]';
            app.BandwithHzKnob_3 = uiknob(app.laSalleEqualizerUIFigure, 'continuous');
            app.BandwithHzKnob_3.Limits = [0 1000];
            app.BandwithHzKnob_3.ValueChangedFcn = createCallbackFcn(app, @BandwithHzKnob_3ValueChanged, true);
            app.BandwithHzKnob_3.Value = 10;
            app.CenterFreqHzKnob_2Label = uilabel(app.laSalleEqualizerUIFigure);
            app.CenterFreqHzKnob_2Label.Text = 'Center Freq [Hz]';
            app.CenterFreqHzKnob_2 = uiknob(app.laSalleEqualizerUIFigure, 'continuous');
            app.CenterFreqHzKnob_2.Limits = [10 8000];
            app.CenterFreqHzKnob_2.ValueChangedFcn = createCallbackFcn(app, @CenterFreqHzKnob_2ValueChanged, true);
            app.CenterFreqHzKnob_2.Value = 10;
            app.GaindBKnob_2Label = uilabel(app.laSalleEqualizerUIFigure);
            app.GaindBKnob_2Label.Text = 'Gain [dB]';
            app.GaindBKnob_2 = uiknob(app.laSalleEqualizerUIFigure, 'continuous');
            app.GaindBKnob_2.Limits = [-40 40];
            app.GaindBKnob_2.ValueChangedFcn = createCallbackFcn(app, @GaindBKnob_2ValueChanged, true);
            app.GaindBKnob_2.Value = 10;
            app.UIAxes = uiaxes(app.laSalleEqualizerUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.laSalleEqualizerUIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.FilterFrequencyReponseLabel = uilabel(app.laSalleEqualizerUIFigure);
            app.FilterFrequencyReponseLabel.Text = 'Filter Frequency Reponse';
            app.ZeroPolePlotLabel = uilabel(app.laSalleEqualizerUIFigure);
            app.ZeroPolePlotLabel.Text = 'Zero-Pole Plot';
            app.Switch = uiswitch(app.laSalleEqualizerUIFigure, 'slider');
            app.Switch.Items = {'Frequency', 'Time'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Frequency';
            app.Switch_2 = uiswitch(app.laSalleEqualizerUIFigure, 'slider');
            app.Switch_2.Items = {'Original', 'Filtered'};
            app.Switch_2.Value = 'Original';
            app.PlayButton = uibutton(app.laSalleEqualizerUIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.laSalleEqualizerUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.UIAxes3 = uiaxes(app.laSalleEqualizerUIFigure);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.EditField = uieditfield(app.laSalleEqualizerUIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.laSalleEqualizerUIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            % Show the figure after all components are created
            app.laSalleEqualizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lsEqualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.laSalleEqualizerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.laSalleEqualizerUIFigure)
        end
    end
end